classdef OD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulationandOrbitalDeterminationProjectUIFigure  matlab.ui.Figure
        Nav                             matlab.ui.container.TabGroup
        tle_reading                     matlab.ui.container.Tab
        LabelEditField                  matlab.ui.control.Label
        Tle_dir                         matlab.ui.control.EditField
        Open                            matlab.ui.control.Button
        Load                            matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        propagation                     matlab.ui.control.Button
        LabelEditField2                 matlab.ui.control.Label
        img_dir                         matlab.ui.control.EditField
        open_img_dir                    matlab.ui.control.Button
        Label11                         matlab.ui.control.Label
        noo                             matlab.ui.control.Spinner
        SteptimeLabel                   matlab.ui.control.Label
        step_time                       matlab.ui.control.Spinner
        Label16                         matlab.ui.control.Label
        obs_time                        matlab.ui.control.Spinner
        ListBoxLabel                    matlab.ui.control.Label
        azelList                        matlab.ui.control.ListBox
        FromEditFieldLabel_2            matlab.ui.control.Label
        From_2                          matlab.ui.control.EditField
        ToEditFieldLabel_2              matlab.ui.control.Label
        To_2                            matlab.ui.control.EditField
        AzimuthEditFieldLabel           matlab.ui.control.Label
        Azimuth                         matlab.ui.control.NumericEditField
        ElevationEditFieldLabel         matlab.ui.control.Label
        Elevation                       matlab.ui.control.NumericEditField
        telescope_setting               matlab.ui.container.Panel
        Label12                         matlab.ui.control.Label
        fov_slider                      matlab.ui.control.Slider
        LabelSlider                     matlab.ui.control.Label
        sao_max_slider                  matlab.ui.control.Slider
        Label13                         matlab.ui.control.Label
        intensity_slider                matlab.ui.control.Slider
        fov                             matlab.ui.control.NumericEditField
        sao_max                         matlab.ui.control.NumericEditField
        intensity                       matlab.ui.control.NumericEditField
        LabelEditField4                 matlab.ui.control.Label
        sao_dir                         matlab.ui.control.EditField
        sao_dir_open                    matlab.ui.control.Button
        generate_sao                    matlab.ui.control.Button
        Station                         matlab.ui.container.Panel
        LabelNumericEditField8          matlab.ui.control.Label
        lon_Sta                         matlab.ui.control.NumericEditField
        Label14                         matlab.ui.control.Label
        lat_Sta                         matlab.ui.control.NumericEditField
        Label15                         matlab.ui.control.Label
        alt_h                           matlab.ui.control.NumericEditField
        LabelSwitch                     matlab.ui.control.Label
        random_view                     matlab.ui.control.Switch
        VisibleontheskyListBoxLabel     matlab.ui.control.Label
        VisibleList                     matlab.ui.control.ListBox
        FromEditFieldLabel              matlab.ui.control.Label
        From                            matlab.ui.control.EditField
        ToEditFieldLabel                matlab.ui.control.Label
        To                              matlab.ui.control.EditField
        EpochTimeEditFieldLabel         matlab.ui.control.Label
        EpochTime                       matlab.ui.control.EditField
        VisibleTimeEditFieldLabel       matlab.ui.control.Label
        VisibleTimeEditField            matlab.ui.control.NumericEditField
        simulation                      matlab.ui.container.Tab
        cam_setting                     matlab.ui.container.Panel
        LabelSpinner4                   matlab.ui.control.Label
        width                           matlab.ui.control.Spinner
        Label17                         matlab.ui.control.Label
        height                          matlab.ui.control.Spinner
        Button3                         matlab.ui.control.Button
        Label19                         matlab.ui.control.Label
        ori                             matlab.ui.control.Spinner
        img_plot                        matlab.ui.control.UIAxes
        Img_Para                        matlab.ui.container.Panel
        LabelListBox                    matlab.ui.control.Label
        img_item                        matlab.ui.control.ListBox
        LabelNumericEditField4          matlab.ui.control.Label
        Azimuth_dis                     matlab.ui.control.NumericEditField
        Label18                         matlab.ui.control.Label
        Elevation_dis                   matlab.ui.control.NumericEditField
        ShowStarID                      matlab.ui.control.StateButton
        azel_ana                        matlab.ui.container.Tab
        Panel4                          matlab.ui.container.Panel
        LabelSwitch2                    matlab.ui.control.Label
        azel_compare                    matlab.ui.control.Switch
        LabelEditField3                 matlab.ui.control.Label
        camera_data_dir                 matlab.ui.control.EditField
        camera_data_dir_open            matlab.ui.control.Button
        Label21                         matlab.ui.control.Label
        image_dir                       matlab.ui.control.EditField
        image_dir_open                  matlab.ui.control.Button
        AzimuthElevationAnalyticsLabel  matlab.ui.control.Label
        azel_result                     matlab.ui.control.TextArea
        azel_analyze                    matlab.ui.control.StateButton
        OrbitalElementLabel             matlab.ui.control.Label
        TLE_EXPORT                      matlab.ui.control.TextArea
        ChooseAZELButtonGroup           matlab.ui.container.ButtonGroup
        UseAZELfromsimulationButton     matlab.ui.control.RadioButton
        UseAZELfromAnalysisButton       matlab.ui.control.RadioButton
        Button9                         matlab.ui.control.Button
        Logging                         matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        Status                          matlab.ui.control.Label
        LabelTextArea                   matlab.ui.control.Label
        Log                             matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.fov.Value = app.fov_slider.Value;
             app.sao_max.Value = app.sao_max_slider.Value;
             app.intensity.Value = app.intensity_slider.Value;
             currentFolder = pwd;
             
             currentFolder = currentFolder(1:length(currentFolder)-length('\Script'));
             
             app.Tle_dir.Value   = strcat(currentFolder,'\Data\SGP4-VER.TLE');
             app.sao_dir.Value   = strcat(currentFolder,'\Data');
             app.img_dir.Value   = strcat(currentFolder,'\Data');
             app.image_dir.Value = strcat(currentFolder,'\Data\Image');
             app.camera_data_dir.Value = strcat(currentFolder,'\Data\Image_Pro.mat');

             app.ShowStarID.Enable = 'Off';
             app.UseAZELfromAnalysisButton.Enable = 'off';
             app.UseAZELfromAnalysisButton.Value = false;
             
             filePattern = fullfile('E:\matlab\ODS-SOFT\Data\', '*.mat');
             theFiles = dir(filePattern);
             for k = 1 : length(theFiles)
               baseFileName = theFiles(k).name;
               fullFileName = fullfile('E:\matlab\ODS-SOFT\Data\', baseFileName);
               delete(fullFileName);
             end
             
             filePattern = fullfile('E:\matlab\ODS-SOFT\Data\Image', '*.png');
             theFiles = dir(filePattern);
             for k = 1 : length(theFiles)
               baseFileName = theFiles(k).name;
               fullFileName = fullfile('E:\matlab\ODS-SOFT\Data\Image', baseFileName);
               delete(fullFileName);
             end
        end

        % Button pushed function: Open
        function OpenButtonPushed(app, event)
            [FileName,FilePath]=uigetfile('E:\matlab\ODS-SOFT\Data\*.TLE');
            app.Tle_dir.Value = strcat(FilePath, FileName);
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            run('E:\matlab\ODS-SOFT\Bin\TLE_LOAD.m');
        end

        % Callback function
        function time_checkingButtonPushed(app, event)
            run('E:\matlab\ODS-SOFT\SOM\Script\Mjdepoch_update.m');
        end

        % Button pushed function: propagation
        function propagationButtonPushed(app, event)
            if app.obs_time.Value > app.step_time.Value
                msgbox('Step time must be greater than Observation time');
            else
                run('E:\matlab\ODS-SOFT\Bin\SGP4_PROP.m');
            end
            app.UseAZELfromAnalysisButton.Enable = 'off';
            app.UseAZELfromAnalysisButton.Value = false;
        end

        % Value changing function: sao_max_slider
        function sao_max_sliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: sao_max_slider
        function sao_max_sliderValueChanged(app, event)
            value = app.sao_max_slider.Value;
            app.sao_max.Value = value;
        end

        % Value changed function: fov_slider
        function fov_sliderValueChanged(app, event)
            value = app.fov_slider.Value;
            app.fov_slider.Value = round(value);
            app.fov.Value = app.fov_slider.Value;
        end

        % Callback function
        function NavSelectionChanged(app, event)
            
        end

        % Button pushed function: Button9
        function Button9ButtonPushed(app, event)
            run('C:\Users\Admin\ODS-SOFTBin\EKF_GEOS3.m');
        end

        % Button pushed function: open_img_dir
        function open_img_dirButtonPushed(app, event)
            selpath = uigetdir('..');
            app.img_dir.Value = selpath;
        end

        % Value changed function: fov
        function fovValueChanged(app, event)
            value = app.fov.Value;
            app.fov_slider.Value = round(value);
            app.fov.Value = app.fov_slider.Value;
        end

        % Value changed function: sao_max
        function sao_maxValueChanged(app, event)
            value = app.sao_max.Value;
            app.sao_max_slider.Value = value;
        end

        % Value changed function: intensity
        function intensityValueChanged(app, event)
            value = app.intensity.Value;
            app.intensity_slider.Value = value;
        end

        % Button pushed function: sao_dir_open
        function sao_dir_openButtonPushed(app, event)
            selpath = uigetdir
            app.sao_dir.Value = selpath;
        end

        % Button pushed function: generate_sao
        function generate_saoButtonPushed(app, event)
            SAOcreate
        end

        % Callback function
        function step_timeValueChanged(app, event)
            global azel_length
            value = app.step_time.Value;
            app.noo.Limits = [1 azel_length/app.step_time.Value];
            
        end

        % Callback function
        function year_2ValueChanged(app, event)
            value = app.year_2.Value;
        end

        % Callback function
        function time_checkingValueChanged(app, event)
            global time_checked 
            time_checked = app.time_checking.Value;
            if time_checked == 1
                run('C:\Users\Admin\ODS-SOFTSOM\Script\Mjdepoch_update.m');
            end
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            app.ShowStarID.Enable = 'Off';
            Generate
        end

        % Value changed function: intensity_slider
        function intensity_sliderValueChanged(app, event)
            value = app.intensity_slider.Value;
            app.intensity.Value = value;
        end

        % Button pushed function: camera_data_dir_open
        function camera_data_dir_openButtonPushed(app, event)
            [FileName,FilePath]=uigetfile('C:\Users\Admin\ODS-SOFTData\*.mat');
            app.camera_data_dir.Value = strcat(FilePath,FileName);
        end

        % Button pushed function: image_dir_open
        function image_dir_openButtonPushed(app, event)
            FilePath=uigetdir('C:\Users\Admin\ODS-SOFTData\');
            app.image_dir.Value = FilePath;
        end

        % Callback function
        function image_loadButtonPushed(app, event)
            value = app.img_item.Value;
            app.Lamp.Color  = 'blue';
            app.Status.Text = 'Loading . . .';
            try
               load(app.camera_data_dir.Value);
               for i = 1:length(Image_Pro.Mjd)
                   imread(Image_Pro.Nam(i,:));
               end
               app.Status.Text = 'Load successfully';
               app.Lamp.Color  = 'green';
            catch
                app.Status.Text = 'Load fail';
                app.Lamp.Color  = 'red';
            end
        end

        % Callback function
        function azel_analyzePushed(app, event)
            
        end

        % Value changed function: azel_analyze
        function azel_analyzeValueChanged(app, event)
            
            if app.azel_analyze.Value == 1
                Find_AZEL
                app.ShowStarID.Enable = 'On';
            else
                app.ShowStarID.Enable = 'Off';
            end
        end

        % Value changed function: img_item
        function img_itemValueChanged(app, event)
            value = app.img_item.Value;
            itemselection   = char(value);
            img         = imread(strcat(app.img_dir.Value,'\Image\',itemselection(8:11),'.png'));
            imshow(img,'Parent',app.img_plot);
            load(strcat(app.img_dir.Value,'\Image_Pro.mat'));
            
            app.Azimuth_dis.Value = Image_Pro.AZ(str2num(itemselection(8:11)));
            app.Elevation_dis.Value = Image_Pro.EL(str2num(itemselection(8:11)));
            if app.ShowStarID.Value == 1
                try
                    load(strcat(app.img_dir.Value,'\StarPos\StarPos',sprintf('%04d',str2num(itemselection(8:11))),'.mat'));
                    hold(app.img_plot,'on');
                    plot(app.img_plot,Data(:,1),Data(:,2),'ob');
                    str = (Data(:,10));
                    text(app.img_plot,Data(:,1)+20,Data(:,2),num2str(str),'Color','w');
                    hold(app.img_plot,'off');
                catch
                end
            end
        end

        % Value changed function: VisibleList
        function VisibleListValueChanged(app, event)
            value = app.VisibleList.Value;
            itemselection   = char(value);
            load E:\matlab\ODS-SOFT\Data\Vis.mat
            addpath('E:\matlab\ODS-SOFT\SGP4\');
            [year,mon,day,hr,minute,sec] = invjday ( Vis(str2num(itemselection(6:8)),1));
            app.From.Value                  = sprintf('%02d/%02d/%04d - %02d:%02d:%02d',day,mon,year,hr,minute,round(sec));
            [year,mon,day,hr,minute,sec]    = invjday ( Vis(str2num(itemselection(6:8)),2));
            app.To.Value                    = sprintf('%02d/%02d/%04d - %02d:%02d:%02d',day,mon,year,hr,minute,round(sec));
            app.VisibleTimeEditField.Value  = Vis(str2num(itemselection(6:8)),3);
            app.noo.Limits = [1 round((app.VisibleTimeEditField.Value*60-app.obs_time.Value*2)/app.step_time.Value)];
            app.noo.Value  = round((app.VisibleTimeEditField.Value*60-app.obs_time.Value*2)/app.step_time.Value);
            rmpath('C:\Users\Admin\ODS-SOFTSGP4\');
        end

        % Value changed function: azelList
        function azelListValueChanged(app, event)
            value = app.azelList.Value;
            itemselection   = char(value);
            addpath('E:\matlab\ODS-SOFT\SGP4\');
            load E:\matlab\ODS-SOFT\Data\Prop_list.mat
            [year,mon,day,hr,minute,sec] = invjday ( Prop_list(str2num(itemselection(6:8)),1));
            app.From_2.Value                  = sprintf('%02d/%02d/%04d - %02d:%02d:%02d',day,mon,year,hr,minute,round(sec));
            [year,mon,day,hr,minute,sec] = invjday ( Prop_list(str2num(itemselection(6:8)),2));
            app.To_2.Value                  = sprintf('%02d/%02d/%04d - %02d:%02d:%02d',day,mon,year,hr,minute,round(sec));
            app.Azimuth.Value            = Prop_list(str2num(itemselection(6:8)),3);
            app.Elevation.Value            = Prop_list(str2num(itemselection(6:8)),4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulationandOrbitalDeterminationProjectUIFigure = uifigure('Visible', 'off');
            app.SimulationandOrbitalDeterminationProjectUIFigure.Name = 'Simulation and Orbital Determination Project';
            app.Nav = uitabgroup(app.SimulationandOrbitalDeterminationProjectUIFigure);
            app.tle_reading = uitab(app.Nav);
            app.tle_reading.Title = 'TLE Reading File';
            app.LabelEditField = uilabel(app.tle_reading);
            app.LabelEditField.Text = 'TLE File';
            app.Tle_dir = uieditfield(app.tle_reading, 'text');
            app.Tle_dir.Value = 'D:\Desktop\OrbitDS _object\Data\SGP4-VER.TLE';
            app.Open = uibutton(app.tle_reading, 'push');
            app.Open.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.Open.Text = 'Open';
            app.Load = uibutton(app.tle_reading, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.Panel = uipanel(app.tle_reading);
            app.Panel.Title = 'Propagation';
            app.propagation = uibutton(app.Panel, 'push');
            app.propagation.ButtonPushedFcn = createCallbackFcn(app, @propagationButtonPushed, true);
            app.propagation.Text = 'Propagation';
            app.LabelEditField2 = uilabel(app.Panel);
            app.LabelEditField2.Text = 'Img Dir';
            app.img_dir = uieditfield(app.Panel, 'text');
            app.img_dir.Editable = 'off';
            app.img_dir.Value = 'D:\Desktop\OrbitDS _object\Data';
            app.open_img_dir = uibutton(app.Panel, 'push');
            app.open_img_dir.ButtonPushedFcn = createCallbackFcn(app, @open_img_dirButtonPushed, true);
            app.open_img_dir.Text = 'Open';
            app.Label11 = uilabel(app.Panel);
            app.Label11.Text = '# of Obs';
            app.noo = uispinner(app.Panel);
            app.noo.Limits = [1 Inf];
            app.noo.Value = 2;
            app.SteptimeLabel = uilabel(app.Panel);
            app.SteptimeLabel.Text = 'Step time';
            app.step_time = uispinner(app.Panel);
            app.step_time.Limits = [1 Inf];
            app.step_time.Value = 5;
            app.Label16 = uilabel(app.Panel);
            app.Label16.Text = 'Obs Time';
            app.obs_time = uispinner(app.Panel);
            app.obs_time.Limits = [1 Inf];
            app.obs_time.Value = 5;
            app.ListBoxLabel = uilabel(app.Panel);
            app.ListBoxLabel.Text = 'List Box';
            app.azelList = uilistbox(app.Panel);
            app.azelList.Items = {};
            app.azelList.ValueChangedFcn = createCallbackFcn(app, @azelListValueChanged, true);
            app.azelList.Value = {};
            app.FromEditFieldLabel_2 = uilabel(app.Panel);
            app.FromEditFieldLabel_2.Text = 'From';
            app.From_2 = uieditfield(app.Panel, 'text');
            app.From_2.Editable = 'off';
            app.ToEditFieldLabel_2 = uilabel(app.Panel);
            app.ToEditFieldLabel_2.Text = 'To';
            app.To_2 = uieditfield(app.Panel, 'text');
            app.To_2.Editable = 'off';
            app.AzimuthEditFieldLabel = uilabel(app.Panel);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.Azimuth = uieditfield(app.Panel, 'numeric');
            app.Azimuth.ValueDisplayFormat = '%2.8f';
            app.Azimuth.Editable = 'off';
            app.ElevationEditFieldLabel = uilabel(app.Panel);
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.Elevation = uieditfield(app.Panel, 'numeric');
            app.Elevation.ValueDisplayFormat = '%2.8f';
            app.Elevation.Editable = 'off';
            app.telescope_setting = uipanel(app.tle_reading);
            app.telescope_setting.Title = 'Telescope Setting';
            app.Label12 = uilabel(app.telescope_setting);
            app.Label12.Text = 'FoV';
            app.fov_slider = uislider(app.telescope_setting);
            app.fov_slider.Limits = [0 20];
            app.fov_slider.MajorTicks = [0 5 10 15 20];
            app.fov_slider.ValueChangedFcn = createCallbackFcn(app, @fov_sliderValueChanged, true);
            app.fov_slider.Value = 5;
            app.LabelSlider = uilabel(app.telescope_setting);
            app.LabelSlider.Text = 'Star Magnitude';
            app.sao_max_slider = uislider(app.telescope_setting);
            app.sao_max_slider.Limits = [0 10];
            app.sao_max_slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.sao_max_slider.ValueChangedFcn = createCallbackFcn(app, @sao_max_sliderValueChanged, true);
            app.sao_max_slider.ValueChangingFcn = createCallbackFcn(app, @sao_max_sliderValueChanging, true);
            app.sao_max_slider.MinorTicks = 0;
            app.sao_max_slider.Value = 8.5;
            app.Label13 = uilabel(app.telescope_setting);
            app.Label13.Text = 'Intensity';
            app.intensity_slider = uislider(app.telescope_setting);
            app.intensity_slider.Limits = [0 1];
            app.intensity_slider.ValueChangedFcn = createCallbackFcn(app, @intensity_sliderValueChanged, true);
            app.intensity_slider.Value = 0.7;
            app.fov = uieditfield(app.telescope_setting, 'numeric');
            app.fov.ValueChangedFcn = createCallbackFcn(app, @fovValueChanged, true);
            app.sao_max = uieditfield(app.telescope_setting, 'numeric');
            app.sao_max.ValueChangedFcn = createCallbackFcn(app, @sao_maxValueChanged, true);
            app.intensity = uieditfield(app.telescope_setting, 'numeric');
            app.intensity.ValueChangedFcn = createCallbackFcn(app, @intensityValueChanged, true);
            app.LabelEditField4 = uilabel(app.telescope_setting);
            app.LabelEditField4.Text = 'SAO data';
            app.sao_dir = uieditfield(app.telescope_setting, 'text');
            app.sao_dir.Value = 'D:\Desktop\OrbitDS _object\Data';
            app.sao_dir_open = uibutton(app.telescope_setting, 'push');
            app.sao_dir_open.ButtonPushedFcn = createCallbackFcn(app, @sao_dir_openButtonPushed, true);
            app.sao_dir_open.Text = 'Open';
            app.generate_sao = uibutton(app.telescope_setting, 'push');
            app.generate_sao.ButtonPushedFcn = createCallbackFcn(app, @generate_saoButtonPushed, true);
            app.generate_sao.Text = 'Create';
            app.Station = uipanel(app.tle_reading);
            app.Station.Title = 'Station';
            app.LabelNumericEditField8 = uilabel(app.Station);
            app.LabelNumericEditField8.Text = 'Longitude';
            app.lon_Sta = uieditfield(app.Station, 'numeric');
            app.Label14 = uilabel(app.Station);
            app.Label14.Text = 'Latitude';
            app.lat_Sta = uieditfield(app.Station, 'numeric');
            app.Label15 = uilabel(app.Station);
            app.Label15.Text = 'Altitude';
            app.alt_h = uieditfield(app.Station, 'numeric');
            app.LabelSwitch = uilabel(app.Station);
            app.LabelSwitch.Enable = 'off';
            app.LabelSwitch.Text = 'Object at center of view:';
            app.random_view = uiswitch(app.Station, 'slider');
            app.random_view.Enable = 'off';
            app.VisibleontheskyListBoxLabel = uilabel(app.tle_reading);
            app.VisibleontheskyListBoxLabel.Text = 'Visible on the sky';
            app.VisibleList = uilistbox(app.tle_reading);
            app.VisibleList.Items = {};
            app.VisibleList.ValueChangedFcn = createCallbackFcn(app, @VisibleListValueChanged, true);
            app.VisibleList.Value = {};
            app.FromEditFieldLabel = uilabel(app.tle_reading);
            app.FromEditFieldLabel.Text = 'From';
            app.From = uieditfield(app.tle_reading, 'text');
            app.ToEditFieldLabel = uilabel(app.tle_reading);
            app.ToEditFieldLabel.Text = 'To';
            app.To = uieditfield(app.tle_reading, 'text');
            app.EpochTimeEditFieldLabel = uilabel(app.tle_reading);
            app.EpochTimeEditFieldLabel.Text = 'Epoch Time';
            app.EpochTime = uieditfield(app.tle_reading, 'text');
            app.EpochTime.Editable = 'off';
            app.VisibleTimeEditFieldLabel = uilabel(app.tle_reading);
            app.VisibleTimeEditFieldLabel.Text = 'Visible Time';
            app.VisibleTimeEditField = uieditfield(app.tle_reading, 'numeric');
            app.simulation = uitab(app.Nav);
            app.simulation.Title = 'Simulation';
            app.cam_setting = uipanel(app.simulation);
            app.cam_setting.Title = 'Camera Setting';
            app.LabelSpinner4 = uilabel(app.cam_setting);
            app.LabelSpinner4.Text = 'Width';
            app.width = uispinner(app.cam_setting);
            app.width.Limits = [1 Inf];
            app.width.Value = 1366;
            app.Label17 = uilabel(app.cam_setting);
            app.Label17.Text = 'Height';
            app.height = uispinner(app.cam_setting);
            app.height.Limits = [1 Inf];
            app.height.Value = 768;
            app.Button3 = uibutton(app.cam_setting, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = 'Generate';
            app.Label19 = uilabel(app.cam_setting);
            app.Label19.Text = 'Orientation';
            app.ori = uispinner(app.cam_setting);
            app.ori.Step = 0.1;
            app.ori.Limits = [0 360];
            app.ori.ValueDisplayFormat = '%3.1f';
            app.img_plot = uiaxes(app.simulation);
            app.Img_Para = uipanel(app.simulation);
            app.Img_Para.Title = 'Image Information';
            app.LabelListBox = uilabel(app.Img_Para);
            app.LabelListBox.Text = 'Frame';
            app.img_item = uilistbox(app.Img_Para);
            app.img_item.Items = {};
            app.img_item.ValueChangedFcn = createCallbackFcn(app, @img_itemValueChanged, true);
            app.img_item.Value = {};
            app.LabelNumericEditField4 = uilabel(app.Img_Para);
            app.LabelNumericEditField4.Text = 'Azimuth';
            app.Azimuth_dis = uieditfield(app.Img_Para, 'numeric');
            app.Azimuth_dis.ValueDisplayFormat = '%3.5f';
            app.Azimuth_dis.Editable = 'off';
            app.Label18 = uilabel(app.Img_Para);
            app.Label18.Text = 'Elevation';
            app.Elevation_dis = uieditfield(app.Img_Para, 'numeric');
            app.Elevation_dis.ValueDisplayFormat = '%3.5f';
            app.Elevation_dis.Editable = 'off';
            app.ShowStarID = uibutton(app.Img_Para, 'state');
            app.ShowStarID.Text = 'Show Star ID';
            app.azel_ana = uitab(app.Nav);
            app.azel_ana.Title = 'Analysis and Determination';
            app.Panel4 = uipanel(app.azel_ana);
            app.Panel4.Title = 'Panel';
            app.LabelSwitch2 = uilabel(app.Panel4);
            app.LabelSwitch2.Text = 'Comparison with Simulation Data';
            app.azel_compare = uiswitch(app.Panel4, 'slider');
            app.LabelEditField3 = uilabel(app.Panel4);
            app.LabelEditField3.Text = 'Camera Data';
            app.camera_data_dir = uieditfield(app.Panel4, 'text');
            app.camera_data_dir_open = uibutton(app.Panel4, 'push');
            app.camera_data_dir_open.ButtonPushedFcn = createCallbackFcn(app, @camera_data_dir_openButtonPushed, true);
            app.camera_data_dir_open.Text = 'Open';
            app.Label21 = uilabel(app.Panel4);
            app.Label21.Text = 'Image set';
            app.image_dir = uieditfield(app.Panel4, 'text');
            app.image_dir_open = uibutton(app.Panel4, 'push');
            app.image_dir_open.ButtonPushedFcn = createCallbackFcn(app, @image_dir_openButtonPushed, true);
            app.image_dir_open.Text = 'Open';
            app.AzimuthElevationAnalyticsLabel = uilabel(app.Panel4);
            app.AzimuthElevationAnalyticsLabel.Text = 'Azimuth & Elevation Analytics';
            app.azel_result = uitextarea(app.Panel4);
            app.azel_result.Editable = 'off';
            app.azel_analyze = uibutton(app.Panel4, 'state');
            app.azel_analyze.ValueChangedFcn = createCallbackFcn(app, @azel_analyzeValueChanged, true);
            app.azel_analyze.Text = 'Analyze';
            app.OrbitalElementLabel = uilabel(app.Panel4);
            app.OrbitalElementLabel.Text = 'Orbital Element';
            app.TLE_EXPORT = uitextarea(app.Panel4);
            app.TLE_EXPORT.Editable = 'off';
            app.ChooseAZELButtonGroup = uibuttongroup(app.Panel4);
            app.ChooseAZELButtonGroup.Title = 'Choose AZ/EL';
            app.UseAZELfromsimulationButton = uiradiobutton(app.ChooseAZELButtonGroup);
            app.UseAZELfromsimulationButton.Text = 'Use AZ/EL from simulation';
            app.UseAZELfromsimulationButton.Value = true;
            app.UseAZELfromAnalysisButton = uiradiobutton(app.ChooseAZELButtonGroup);
            app.UseAZELfromAnalysisButton.Text = 'Use AZ/EL from Analysis';
            app.Button9 = uibutton(app.ChooseAZELButtonGroup, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9ButtonPushed, true);
            app.Button9.Text = 'Orbital Determination';
            app.Logging = uipanel(app.SimulationandOrbitalDeterminationProjectUIFigure);
            app.Logging.Title = 'Panel';
            app.Lamp = uilamp(app.Logging);
            app.Lamp.Enable = 'off';
            app.Status = uilabel(app.Logging);
            app.Status.Text = '';
            app.LabelTextArea = uilabel(app.Logging);
            app.LabelTextArea.Text = 'Log';
            app.Log = uitextarea(app.Logging);
            app.Log.Editable = 'off';
            % Show the figure after all components are created
            app.SimulationandOrbitalDeterminationProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulationandOrbitalDeterminationProjectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulationandOrbitalDeterminationProjectUIFigure)
        end
    end
end